classdef WDSignalExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MainGrid              matlab.ui.container.GridLayout
        StatusPanel           matlab.ui.container.Panel
        StatusGrid            matlab.ui.container.GridLayout
        LicenseLabel          matlab.ui.control.Label
        StatusLabel           matlab.ui.control.Label
        ContentPanel          matlab.ui.container.Panel
        ContentGrid           matlab.ui.container.GridLayout
        MetricsTable          matlab.ui.control.Table
        SignalAxes            matlab.ui.control.UIAxes
        ControlPanel          matlab.ui.container.Panel
        ControlGrid           matlab.ui.container.GridLayout
        ExportButton          matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        SidebarSpacerLabel    matlab.ui.control.Label
        SidebarHintLabel      matlab.ui.control.Label
        ViewDropDown          matlab.ui.control.DropDown
        ViewLabel             matlab.ui.control.Label
        CutoffField           matlab.ui.control.NumericEditField
        CutoffLabel           matlab.ui.control.Label
        FilterCheckBox        matlab.ui.control.CheckBox
        DetrendCheckBox       matlab.ui.control.CheckBox
        SampleRateField       matlab.ui.control.NumericEditField
        SampleRateLabel       matlab.ui.control.Label
        SignalColumnDropDown  matlab.ui.control.DropDown
        SignalColumnLabel     matlab.ui.control.Label
        TimeColumnDropDown    matlab.ui.control.DropDown
        TimeColumnLabel       matlab.ui.control.Label
        SampleDataButton      matlab.ui.control.Button
        LoadCSVButton         matlab.ui.control.Button
        HeaderPanel           matlab.ui.container.Panel
        HeaderGrid            matlab.ui.container.GridLayout
        FileLabel             matlab.ui.control.Label
        TitleLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        CurrentFile = 'Generated sample data'
        ProcessedData
        RawData
        TimeData
    end

    methods (Access = private)
        function tbl = createSampleTable(app)
            fs = 2000;
            t = (0:1/fs:5-1/fs)';
            rng default;
            positionError = 1.8*sin(2*pi*120*t).*exp(-0.35*t) + 0.25*randn(size(t));
            motorCurrent = 1.2 + 0.18*sin(2*pi*8*t) + 0.08*sin(2*pi*120*t) + 0.03*randn(size(t));
            acceleration = 0.06*sin(2*pi*120*t) + 0.02*sin(2*pi*250*t) + 0.015*randn(size(t));
            tbl = table(t, positionError, motorCurrent, acceleration, 'VariableNames', {'Time_s','PositionError_um','MotorCurrent_A','Acceleration_g'});
        end
        function [t, y] = processSignal(app)
            t = double(app.RawData.(app.TimeColumnDropDown.Value));
            y = double(app.RawData.(app.SignalColumnDropDown.Value));
            valid = isfinite(t) & isfinite(y);
            t = t(valid);
            y = y(valid);
            if app.DetrendCheckBox.Value
                y = detrend(y, 0);
            end
            if app.FilterCheckBox.Value
                fs = app.SampleRateField.Value;
                cutoff = min(app.CutoffField.Value, 0.49*fs);
                y = lowpass(y, cutoff, fs);
            end
            app.TimeData = t;
            app.ProcessedData = y;
        end
        function setTable(app, tbl, fileName)
            numericMask = varfun(@isnumeric, tbl, 'OutputFormat', 'uniform');
            names = string(tbl.Properties.VariableNames(numericMask));
            if numel(names) < 2
                uialert(app.UIFigure, 'The file needs at least two numeric columns.', 'Unsupported data');
                return
            end
            timeIndex = find(contains(lower(names), 'time'), 1);
            if isempty(timeIndex)
                timeIndex = 1;
            end
            signalNames = names;
            signalNames(timeIndex) = [];
            app.RawData = tbl;
            app.TimeColumnDropDown.Items = cellstr(names);
            app.TimeColumnDropDown.Value = char(names(timeIndex));
            app.SignalColumnDropDown.Items = cellstr(signalNames);
            app.SignalColumnDropDown.Value = char(signalNames(1));
            t = double(tbl.(char(names(timeIndex))));
            validDelta = diff(t(isfinite(t)));
            validDelta = validDelta(validDelta > 0);
            if ~isempty(validDelta)
                app.SampleRateField.Value = 1/median(validDelta);
            end
            app.CurrentFile = fileName;
            app.FileLabel.Text = fileName;
            app.updateAnalysis();
        end
        function updateAnalysis(app)
            if isempty(app.RawData)
                return
            end
            [t, y] = app.processSignal();
            app.updatePlot(t, y);
            app.updateMetrics(t, y);
            app.StatusLabel.Text = sprintf('Ready: %d valid samples', numel(y));
        end
        function updateMetrics(app, t, y)
            fs = app.SampleRateField.Value;
            [pxx, f] = pwelch(y, [], [], [], fs);
            [~, peakIndex] = max(pxx);
            dominantFrequency = f(peakIndex);
            metrics = {'Samples'; 'Duration (s)'; 'RMS'; 'Peak-to-peak'; 'Dominant frequency (Hz)'};
            values = [numel(y); max(t)-min(t); rms(y); peak2peak(y); dominantFrequency];
            app.MetricsTable.Data = table(metrics, values, 'VariableNames', {'Metric','Value'});
        end
        function updatePlot(app, t, y)
            cla(app.SignalAxes);
            if strcmp(app.ViewDropDown.Value, 'Time domain')
                plot(app.SignalAxes, t, y, 'LineWidth', 1.2);
                title(app.SignalAxes, app.SignalColumnDropDown.Value, 'Interpreter', 'none');
                xlabel(app.SignalAxes, app.TimeColumnDropDown.Value, 'Interpreter', 'none');
                ylabel(app.SignalAxes, 'Amplitude');
            else
                fs = app.SampleRateField.Value;
                [pxx, f] = pwelch(y, [], [], [], fs);
                plot(app.SignalAxes, f, pow2db(pxx), 'LineWidth', 1.2);
                title(app.SignalAxes, 'Power spectral density');
                xlabel(app.SignalAxes, 'Frequency (Hz)');
                ylabel(app.SignalAxes, 'PSD (dB/Hz)');
            end
            grid(app.SignalAxes, 'on');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RawData = table();
            app.TimeData = [];
            app.ProcessedData = [];
            tbl = app.createSampleTable();
            app.setTable(tbl, 'Generated sample data');
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            [file, folder] = uigetfile('*.csv', 'Select measurement CSV');
            if isequal(file, 0)
                return
            end
            app.StatusLabel.Text = 'Loading data...';
            drawnow;
            tbl = readtable(fullfile(folder, file), 'VariableNamingRule', 'preserve');
            app.setTable(tbl, file);
        end

        % Button pushed function: SampleDataButton
        function SampleDataButtonPushed(app, event)
            tbl = app.createSampleTable();
            app.setTable(tbl, 'Generated sample data');
        end

        % Value changed function: CutoffField, DetrendCheckBox, 
        % ...and 5 other components
        function ControlsChanged(app, event)
            app.updateAnalysis();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.DetrendCheckBox.Value = true;
            app.FilterCheckBox.Value = false;
            app.CutoffField.Value = min(300, 0.49*app.SampleRateField.Value);
            app.ViewDropDown.Value = 'Time domain';
            app.updateAnalysis();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.ProcessedData)
                return
            end
            [file, folder] = uiputfile('processed_signal.csv', 'Export processed signal');
            if isequal(file, 0)
                return
            end
            outputTable = table(app.TimeData, app.ProcessedData, 'VariableNames', {'Time','ProcessedSignal'});
            writetable(outputTable, fullfile(folder, file));
            app.StatusLabel.Text = ['Exported: ' file];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'WDSignalExplorer';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.HeaderPanel = uipanel(app.MainGrid);
            app.HeaderGrid = uigridlayout(app.HeaderPanel);
            app.TitleLabel = uilabel(app.HeaderGrid);
            app.TitleLabel.Text = 'WD Signal Explorer';
            app.FileLabel = uilabel(app.HeaderGrid);
            app.FileLabel.Text = 'Generated sample data';
            app.ControlPanel = uipanel(app.MainGrid);
            app.ControlPanel.Title = 'Data and processing';
            app.ControlGrid = uigridlayout(app.ControlPanel);
            app.LoadCSVButton = uibutton(app.ControlGrid, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.SampleDataButton = uibutton(app.ControlGrid, 'push');
            app.SampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @SampleDataButtonPushed, true);
            app.SampleDataButton.Text = 'Generate sample servo data';
            app.TimeColumnLabel = uilabel(app.ControlGrid);
            app.TimeColumnLabel.Text = 'Time column';
            app.TimeColumnDropDown = uidropdown(app.ControlGrid);
            app.TimeColumnDropDown.Items = {'Time_s'};
            app.TimeColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.TimeColumnDropDown.Value = 'Time_s';
            app.SignalColumnLabel = uilabel(app.ControlGrid);
            app.SignalColumnLabel.Text = 'Signal column';
            app.SignalColumnDropDown = uidropdown(app.ControlGrid);
            app.SignalColumnDropDown.Items = {'PositionError_um'};
            app.SignalColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.SignalColumnDropDown.Value = 'PositionError_um';
            app.SampleRateLabel = uilabel(app.ControlGrid);
            app.SampleRateLabel.Text = 'Sample rate (Hz)';
            app.SampleRateField = uieditfield(app.ControlGrid, 'numeric');
            app.SampleRateField.Limits = [0.001 Inf];
            app.SampleRateField.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.SampleRateField.Value = 2000;
            app.DetrendCheckBox = uicheckbox(app.ControlGrid);
            app.DetrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.DetrendCheckBox.Text = 'Remove mean';
            app.DetrendCheckBox.Value = true;
            app.FilterCheckBox = uicheckbox(app.ControlGrid);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.FilterCheckBox.Text = 'Apply low-pass filter';
            app.CutoffLabel = uilabel(app.ControlGrid);
            app.CutoffLabel.Text = 'Low-pass cutoff (Hz)';
            app.CutoffField = uieditfield(app.ControlGrid, 'numeric');
            app.CutoffField.Limits = [0.001 Inf];
            app.CutoffField.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.CutoffField.Value = 300;
            app.ViewLabel = uilabel(app.ControlGrid);
            app.ViewLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.ControlGrid);
            app.ViewDropDown.Items = {'Time domain', 'Frequency domain'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlsChanged, true);
            app.ViewDropDown.Value = 'Time domain';
            app.SidebarHintLabel = uilabel(app.ControlGrid);
            app.SidebarHintLabel.Text = 'Change a control to refresh the analysis.';
            app.SidebarSpacerLabel = uilabel(app.ControlGrid);
            app.SidebarSpacerLabel.Text = '';
            app.ResetButton = uibutton(app.ControlGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset processing';
            app.ExportButton = uibutton(app.ControlGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export processed CSV';
            app.ContentPanel = uipanel(app.MainGrid);
            app.ContentPanel.Title = 'Analysis';
            app.ContentGrid = uigridlayout(app.ContentPanel);
            app.SignalAxes = uiaxes(app.ContentGrid);
            title(app.SignalAxes, 'Signal')
            xlabel(app.SignalAxes, 'Time (s)')
            ylabel(app.SignalAxes, 'Amplitude')
            app.MetricsTable = uitable(app.ContentGrid);
            app.MetricsTable.ColumnName = {'Metric'; 'Value'};
            app.MetricsTable.RowName = {};
            app.StatusPanel = uipanel(app.MainGrid);
            app.StatusGrid = uigridlayout(app.StatusPanel);
            app.StatusLabel = uilabel(app.StatusGrid);
            app.StatusLabel.Text = 'Ready';
            app.LicenseLabel = uilabel(app.StatusGrid);
            app.LicenseLabel.Text = 'MATLAB + Signal Processing Toolbox';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WDSignalExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end